classdef neurons < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        MainAxes                   matlab.ui.control.UIAxes
        OpenButton                 matlab.ui.control.Button
        ZoomCheckBox               matlab.ui.control.CheckBox
        RefreshButton              matlab.ui.control.Button
        Restore                    matlab.ui.control.Button
        PreprocessPanel            matlab.ui.container.Panel
        scaleofresizeLabel         matlab.ui.control.Label
        threshold_min              matlab.ui.control.NumericEditField
        levelofhistogramadjustmentLabel  matlab.ui.control.Label
        level_low                  matlab.ui.control.NumericEditField
        disksizeofenhancingcontrastLabel  matlab.ui.control.Label
        partitionx                 matlab.ui.control.NumericEditField
        Run_preprocess             matlab.ui.control.Button
        level_high                 matlab.ui.control.NumericEditField
        Label_range                matlab.ui.control.Label
        parametersinremovingunconnectedneuronsLabel  matlab.ui.control.Label
        label_threLabel            matlab.ui.control.Label
        bw_min                     matlab.ui.control.NumericEditField
        intensity_threLabel        matlab.ui.control.Label
        intensity_thre             matlab.ui.control.NumericEditField
        extent_threLabel           matlab.ui.control.Label
        extent_thre                matlab.ui.control.NumericEditField
        area_threLabel             matlab.ui.control.Label
        area_thre                  matlab.ui.control.NumericEditField
        binary_threLabel           matlab.ui.control.Label
        extent_thre_2              matlab.ui.control.NumericEditField
        min_pixel_numLabel         matlab.ui.control.Label
        extent_thre_3              matlab.ui.control.NumericEditField
        Label                      matlab.ui.control.Label
        NeurondetectionPanel       matlab.ui.container.Panel
        min_radiusLabel            matlab.ui.control.Label
        num_peaks                  matlab.ui.control.NumericEditField
        Run_neurons                matlab.ui.control.Button
        density_thresholdLabel     matlab.ui.control.Label
        theta_space                matlab.ui.control.NumericEditField
        anglethresholdLabel        matlab.ui.control.Label
        angle_thre                 matlab.ui.control.NumericEditField
        around_radiusLabel         matlab.ui.control.Label
        R_center                   matlab.ui.control.NumericEditField
        max_radiusLabel            matlab.ui.control.Label
        num_peaks_2                matlab.ui.control.NumericEditField
        aroundthresholdLabel       matlab.ui.control.Label
        angle_thre_2               matlab.ui.control.NumericEditField
        synapsedetectionPanel      matlab.ui.container.Panel
        Run_synapse                matlab.ui.control.Button
        linkdistanceLabel          matlab.ui.control.Label
        lin_dis                    matlab.ui.control.NumericEditField
        linkdegreedifferenceLabel  matlab.ui.control.Label
        lin_dis_2                  matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        img = [];
        img_before = [];
        Neurons = [];
        R = [];
        grades = [];
        connectivity = [];
        label = [];
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            welcome = imread('welcome.tif');
            image(app.MainAxes, welcome);
            app.Label.Text = 'Welcome';
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            app.Label.Text = 'Select image';
            [filename, pathname] = uigetfile({'*.jpg;*.tif;*.png;*.gif','All Image Files';...
                '*.*','All Files' });
            if isequal(filename,0) || isequal(pathname,0)
                errordlg('no selected files','Error');
                return;
            else
                file=[pathname,filename];
            end
            app.Label.Text = 'Processing...';
            app.img = imread(file);
            app.img = uint16(app.img);
            [m, n] = size(app.img);
            app.MainAxes.XLim = [1, n];
            app.MainAxes.YLim = [1, m];
            colormap(app.MainAxes, gray);
            imshow(app.img, [], 'parent', app.MainAxes);
            app.img_before = app.img;
            app.Label.Text = 'leisure';
        end

        % Value changed function: ZoomCheckBox
        function ZoomCheckBoxValueChanged(app, event)
            value = app.ZoomCheckBox.Value;
            if value == 1
                pan(app.MainAxes,'on');
                zoom(app.MainAxes,'on');
            else
                pan(app.MainAxes,'off');
                zoom(app.MainAxes,'off'); 
            end
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.Label.Text = 'Processing...';
            imshow(app.img, 'parent', app.MainAxes);
            [m, n] = size(app.img);
            app.MainAxes.XLim = [1, n];
            app.MainAxes.YLim = [1, m]; 
            app.Label.Text = 'leisure';
        end

        % Button pushed function: Run_preprocess
        function Run_preprocessButtonPushed(app, event)
            app.Label.Text = 'Processing...';
        
            imshow(img_new > 0, 'parent', app.MainAxes);
            [m, n] = size(img_new);
            app.MainAxes.XLim = [1, n];
            app.MainAxes.YLim = [1, m];
            app.img_before = app.img;
            app.img = img_new;
            app.Label.Text = 'leisure';
        end

        % Button pushed function: Restore
        function RestorePushed(app, event)
            app.Label.Text = 'Processing...';
            app.img = app.img_before;
            imshow(app.img, 'parent', app.MainAxes);
            app.Label.Text = 'leisure';
        end

        % Callback function
        function Run_selectButtonPushed(app, event)
            app.Label.Text = 'Processing...';
            BW = app.img > 0;
            [m, n] = size(BW);
            M = app.px.Value; N = app.py.Value;
            row = ones(1, M) * m / M;
            col = ones(1, N) * n / N;
            % partition
            roi_parts = mat2cell(app.img, row, col);
            BW_parts = mat2cell(BW, row, col);          
            % select and save
            r = app.select_x.Value;
            c = app.select_y.Value;
            app.img = roi_parts{r,c};
            % show
            BW_p = BW_parts{r,c};
            imshow(BW_p, 'parent', app.MainAxes);            
            [m, n] = size(BW_p);
            app.MainAxes.XLim = [1, n];
            app.MainAxes.YLim = [1, m];
            app.Label.Text = 'leisure';
        end

        % Button pushed function: Run_neurons
        function Run_neuronsButtonPushed(app, event)
            app.Label.Text = 'Processing...';
            % detect lines
            roi = app.img;
            BW = roi > 0;
            [~, lines] = line_detection(roi, app.num_peaks.Value, ...
                            'theta_space', app.theta_space.Value, ...
                            'rho_space', app.rho_space.Value, ...
                            'min_length', app.min_len.Value);
            % extend lines
            extended_lines = lines_extend(lines, app.ex_len.Value);
            % intersect
            points = line_intersect_points(extended_lines, size(BW,1), size(BW,2));
            % draw
            draw_lines(BW, extended_lines); hold on;
            axis on, xlabel x, ylabel y;
            plot(points(:,1),points(:,2),'.','color','blue', 'MarkerSize', 8);
            % detect neurons
            [app.Neurons, app.grades, app.R] = ...
                IsNeurons_new(BW, points, ...
                        'threshold_angle', app.angle_thre.Value, ...
                        'R', app.R_center.Value, ...
                        'R_range', app.R_range.Value);
            % show
            hold(app.MainAxes, 'on');
            imshow(BW, 'parent', app.MainAxes);
            % plot neurons
            plot(app.MainAxes, app.Neurons(:,1),app.Neurons(:,2),'.','color','red', 'MarkerSize', 8);
            [M, N] = size(BW);
            num = length(app.R);
            % plot circles
            for k = 1:num
                % generate meshgrid
                annulus = 1;
                row = max(1, app.Neurons(k,1) - app.R(k) - annulus):...
                    min(N, app.Neurons(k,1) + app.R(k) + annulus);
                col = max(1, app.Neurons(k,2) - app.R(k) - annulus)...
                    :min(M, app.Neurons(k,2) + app.R(k) + annulus);
                [area_x, area_y] = meshgrid(row, col);
                % distance 
                dist = sqrt((area_x - app.Neurons(k,1)).^2 + (area_y - app.Neurons(k,2)).^2);
                % number of points in the circular area of points(k)
                in_circle = dist >= app.R(k) - annulus & dist <= app.R(k) + annulus;
                plot(app.MainAxes, area_x(in_circle), area_y(in_circle), '.','color','red', 'MarkerSize', 1);
            end
            hold(app.MainAxes, 'off');
            app.Label.Text = 'leisure';
        end

        % Button pushed function: Run_synapse
        function Run_synapseButtonPushed(app, event)
            app.Label.Text = 'leisure';
            [app.connectivity, app.label] = queue_synapse(app.Neurons, app.img, app.R);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.MainAxes = uiaxes(app.UIFigure);
            xlabel(app.MainAxes, 'X')
            ylabel(app.MainAxes, 'Y')
            app.OpenButton = uibutton(app.UIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.ZoomCheckBox = uicheckbox(app.UIFigure);
            app.ZoomCheckBox.ValueChangedFcn = createCallbackFcn(app, @ZoomCheckBoxValueChanged, true);
            app.ZoomCheckBox.Text = 'Zoom';
            app.RefreshButton = uibutton(app.UIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.Restore = uibutton(app.UIFigure, 'push');
            app.Restore.ButtonPushedFcn = createCallbackFcn(app, @RestorePushed, true);
            app.Restore.Text = {'RESTORE '; 'image to last action'};
            app.PreprocessPanel = uipanel(app.UIFigure);
            app.PreprocessPanel.Title = '1. Preprocess';
            app.scaleofresizeLabel = uilabel(app.PreprocessPanel);
            app.scaleofresizeLabel.Text = 'scale of resize :';
            app.threshold_min = uieditfield(app.PreprocessPanel, 'numeric');
            app.threshold_min.Value = 0.5;
            app.levelofhistogramadjustmentLabel = uilabel(app.PreprocessPanel);
            app.levelofhistogramadjustmentLabel.Text = 'level of histogram adjustment :';
            app.level_low = uieditfield(app.PreprocessPanel, 'numeric');
            app.level_low.Value = 0.3;
            app.disksizeofenhancingcontrastLabel = uilabel(app.PreprocessPanel);
            app.disksizeofenhancingcontrastLabel.Text = 'disk size of enhancing contrast :';
            app.partitionx = uieditfield(app.PreprocessPanel, 'numeric');
            app.partitionx.Value = 1;
            app.Run_preprocess = uibutton(app.PreprocessPanel, 'push');
            app.Run_preprocess.ButtonPushedFcn = createCallbackFcn(app, @Run_preprocessButtonPushed, true);
            app.Run_preprocess.Text = 'Run';
            app.level_high = uieditfield(app.PreprocessPanel, 'numeric');
            app.level_high.Value = 0.98;
            app.Label_range = uilabel(app.PreprocessPanel);
            app.Label_range.Text = '~';
            app.parametersinremovingunconnectedneuronsLabel = uilabel(app.PreprocessPanel);
            app.parametersinremovingunconnectedneuronsLabel.Text = 'parameters in removing unconnected neurons :';
            app.label_threLabel = uilabel(app.PreprocessPanel);
            app.label_threLabel.Text = 'label_thre: ';
            app.bw_min = uieditfield(app.PreprocessPanel, 'numeric');
            app.bw_min.Value = 20;
            app.intensity_threLabel = uilabel(app.PreprocessPanel);
            app.intensity_threLabel.Text = 'intensity_thre:';
            app.intensity_thre = uieditfield(app.PreprocessPanel, 'numeric');
            app.intensity_thre.Value = 170;
            app.extent_threLabel = uilabel(app.PreprocessPanel);
            app.extent_threLabel.Text = 'extent_thre:';
            app.extent_thre = uieditfield(app.PreprocessPanel, 'numeric');
            app.extent_thre.Value = 0.3;
            app.area_threLabel = uilabel(app.PreprocessPanel);
            app.area_threLabel.Text = 'area_thre:';
            app.area_thre = uieditfield(app.PreprocessPanel, 'numeric');
            app.area_thre.Value = 300;
            app.binary_threLabel = uilabel(app.PreprocessPanel);
            app.binary_threLabel.Text = 'binary_thre:';
            app.extent_thre_2 = uieditfield(app.PreprocessPanel, 'numeric');
            app.extent_thre_2.Value = 40;
            app.min_pixel_numLabel = uilabel(app.PreprocessPanel);
            app.min_pixel_numLabel.Text = 'min_pixel_num';
            app.extent_thre_3 = uieditfield(app.PreprocessPanel, 'numeric');
            app.extent_thre_3.Value = 40;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.NeurondetectionPanel = uipanel(app.UIFigure);
            app.NeurondetectionPanel.Title = '2. Neuron detection';
            app.min_radiusLabel = uilabel(app.NeurondetectionPanel);
            app.min_radiusLabel.Text = 'min_radius: ';
            app.num_peaks = uieditfield(app.NeurondetectionPanel, 'numeric');
            app.num_peaks.Value = 10;
            app.Run_neurons = uibutton(app.NeurondetectionPanel, 'push');
            app.Run_neurons.ButtonPushedFcn = createCallbackFcn(app, @Run_neuronsButtonPushed, true);
            app.Run_neurons.Text = 'Run';
            app.density_thresholdLabel = uilabel(app.NeurondetectionPanel);
            app.density_thresholdLabel.Text = 'density_threshold: ';
            app.theta_space = uieditfield(app.NeurondetectionPanel, 'numeric');
            app.theta_space.Value = 0.4;
            app.anglethresholdLabel = uilabel(app.NeurondetectionPanel);
            app.anglethresholdLabel.Text = 'angle threshold: ';
            app.angle_thre = uieditfield(app.NeurondetectionPanel, 'numeric');
            app.angle_thre.Value = 0.7;
            app.around_radiusLabel = uilabel(app.NeurondetectionPanel);
            app.around_radiusLabel.Text = 'around_radius: ';
            app.R_center = uieditfield(app.NeurondetectionPanel, 'numeric');
            app.R_center.Value = 6;
            app.max_radiusLabel = uilabel(app.NeurondetectionPanel);
            app.max_radiusLabel.Text = 'max_radius: ';
            app.num_peaks_2 = uieditfield(app.NeurondetectionPanel, 'numeric');
            app.num_peaks_2.Value = 25;
            app.aroundthresholdLabel = uilabel(app.NeurondetectionPanel);
            app.aroundthresholdLabel.Text = 'around threshold: ';
            app.angle_thre_2 = uieditfield(app.NeurondetectionPanel, 'numeric');
            app.angle_thre_2.Value = 0.2;
            app.synapsedetectionPanel = uipanel(app.UIFigure);
            app.synapsedetectionPanel.Title = '3. synapse detection';
            app.Run_synapse = uibutton(app.synapsedetectionPanel, 'push');
            app.Run_synapse.ButtonPushedFcn = createCallbackFcn(app, @Run_synapseButtonPushed, true);
            app.Run_synapse.Text = 'Run';
            app.linkdistanceLabel = uilabel(app.synapsedetectionPanel);
            app.linkdistanceLabel.Text = 'link distance: ';
            app.lin_dis = uieditfield(app.synapsedetectionPanel, 'numeric');
            app.lin_dis.Value = 15;
            app.linkdegreedifferenceLabel = uilabel(app.synapsedetectionPanel);
            app.linkdegreedifferenceLabel.Text = 'link degree difference: ';
            app.lin_dis_2 = uieditfield(app.synapsedetectionPanel, 'numeric');
            app.lin_dis_2.Value = 15;
        end
    end

    methods (Access = public)

        % Construct app
        function app = neurons

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end